classdef bearingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BearingAnalyzerUIFigure         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        AnalyzerTab                     matlab.ui.container.Tab
        BearingSetupPanel               matlab.ui.container.Panel
        RollerDiameterEditFieldLabel    matlab.ui.control.Label
        RollerDiameterEditField         matlab.ui.control.NumericEditField
        ContactAngleDegreeEditFieldLabel  matlab.ui.control.Label
        ContactAngleDegreeEditField     matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        SideDropDownLabel               matlab.ui.control.Label
        SideDropDown                    matlab.ui.control.DropDown
        PitchDiameterEditFieldLabel     matlab.ui.control.Label
        PitchDiameterEditField          matlab.ui.control.NumericEditField
        AnalyzerSetupPanel              matlab.ui.container.Panel
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        SampleSetupPanel                matlab.ui.container.Panel
        SampleRateEditFieldLabel        matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        LoadEditFieldLabel              matlab.ui.control.Label
        LoadEditField                   matlab.ui.control.NumericEditField
        ShaftRateEditFieldLabel         matlab.ui.control.Label
        ShaftRateEditField              matlab.ui.control.NumericEditField
        DataDropDownLabel               matlab.ui.control.Label
        DataDropDown                    matlab.ui.control.DropDown
        UIAxes2                         matlab.ui.control.UIAxes
        CommandsPanel                   matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        InfoTextAreaLabel               matlab.ui.control.Label
        InfoTextArea                    matlab.ui.control.TextArea
        FilesListBoxLabel               matlab.ui.control.Label
        FilesListBox                    matlab.ui.control.ListBox
        AddFileButton                   matlab.ui.control.Button
        RemoveFileButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FaultFrequenciesPanel           matlab.ui.container.Panel
        BallEditFieldLabel              matlab.ui.control.Label
        BallEditField                   matlab.ui.control.NumericEditField
        CageEditFieldLabel              matlab.ui.control.Label
        CageEditField                   matlab.ui.control.NumericEditField
        InnerEditFieldLabel             matlab.ui.control.Label
        InnerEditField                  matlab.ui.control.NumericEditField
        OuterEditFieldLabel             matlab.ui.control.Label
        OuterEditField                  matlab.ui.control.NumericEditField
        RevEditFieldLabel               matlab.ui.control.Label
        RevEditField                    matlab.ui.control.NumericEditField
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
    end


    properties (Access = private)
        filename
        data
        samples
        info % Description
        fault
        images
        videos
        lamp
    end

    methods (Access = private)
        % https://www.mathworks.com/help/releases/R2016b/matlab/creating_guis/graphics-support-in-app-designer.html
        function plotBearingFault(app)
            reset(app.UIAxes);
            plot(app.UIAxes, app.fault.freq, app.fault.spec);
            ax = [xlim(app.UIAxes) ylim(app.UIAxes)];
            hold(app.UIAxes, 'on')
            for i = 1:5
                f = app.fault.freqs(i);
                plot(app.UIAxes, [f f], ax(3:4),'LineWidth',2)
            end
            plot(app.UIAxes, app.fault.pks.loc, app.fault.pks.val, 'o');
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'env', 'cage', 'ball', 'outer', 'inner', '1/rev')
            xlabel(app.UIAxes, 'Hz')
            ylabel(app.UIAxes, 'Gs')
            xlim(app.UIAxes, [0 200]);
            ylim(app.UIAxes, ax(3:4));
        end

        function LampDisplay(app)
            if (app.fault.pks.status(app.fault.pks.status > 0))
                app.StatusLamp.Color = 'red';
                app.StatusLamp.Enable = 'on';
            else
                app.StatusLamp.Color = 'green';
                app.StatusLamp.Enable = 'on';
            end
        end
        
        function ImageDisplay(app)
            if (app.fault.pks.status(3) > 0)
                imshow(app.images.outer, 'Parent', app.UIAxes2);
            elseif (app.fault.pks.status(4) > 0)
                imshow(app.images.inner, 'Parent', app.UIAxes2);
            else
                imshow(app.images.norm, 'Parent', app.UIAxes2);
            end
        end

        function VideoDisplay(app)
            if (app.fault.pks.status(3) > 0)
                PlayVideo(app, app.videos.outer);
            elseif (app.fault.pks.status(4) > 0)
                PlayVideo(app, app.videos.inner);
            else
                PlayVideo(app, app.videos.norm);
            end
        end
        
        function InitLamp(app)
            app.StatusLamp.Color = [0.65 0.65 0.65];
            app.StatusLamp.Enable = 'off';
        end
        
        function InitImage(app)
            imshow(app.images.norm, 'Parent', app.UIAxes2);
        end

        function InitVideo(app)
            PlayVideo(app, app.videos.default);
        end
        
        function LoadImages(app)
            app.images.norm = imread('BallBearing.png');
            app.images.inner = imread('BallBearingInnerRace.png');
            app.images.outer = imread('BallBearingOuterRace.png');
        end

        function LoadVideos(app)
            app.videos.default = VideoReader('BallBearing v9.mp4');
            app.videos.norm = VideoReader('BallBearingNormal v3.mp4');
            app.videos.inner = VideoReader('BallBearingInnerRace v6.mp4');
            app.videos.outer = VideoReader('BallBearingOuterRace v7.mp4');
        end

        function PlayVideo(app, vidObj)
            vidObj.CurrentTime = 0.0;
            while hasFrame(vidObj)
                vidFrame = readFrame(vidObj);
                image(vidFrame, 'Parent', app.UIAxes2);
                app.UIAxes2.Visible = 'off';
                pause(1/vidObj.FrameRate);
            end
        end
    
        function LoadFile(app)
            d = uiprogressdlg(app.BearingAnalyzerUIFigure,'Title','Loading ...', 'Indeterminate','on');
            app.filename = app.FilesListBox.Value;
            [filepath, name, ext] = fileparts(app.filename);
            InitLamp(app);
            %InitImage(app);
            InitVideo(app);
            
            if (numel(app.filename) > 0)
                if (strcmp(ext, '.mat'))
                    app.samples = load(app.filename);
                    if (isfield(app.samples, 'bearing'))
                        app.InfoTextArea.Value = 'Loaded bearing data. Please review bearing setup carefully before running analysis.';
                        % Transfer pre-formatted data
                        app.data = app.samples;
                        % Extract fields from pre-formatted data
                        app.ShaftRateEditField.Value = app.data.bearing.rate;
                        app.SampleRateEditField.Value = app.data.bearing.sr;
                        if (ischar(app.data.bearing.load))
                            app.LoadEditField.Value = str2double(app.data.bearing.load);
                        else
                            app.LoadEditField.Value = app.data.bearing.load;
                        end
                    else
                        app.InfoTextArea.Value = 'Loaded bearing samples. Please review bearing and sample setup and carefully before running analysis.';
                        app.DataDropDown.Items = fieldnames(app.samples)';
                        app.data.bearing.gs = app.samples.(app.DataDropDown.Value);
                    end
                elseif (strcmp(ext, '.csv'))
                    app.samples = csvread(app.filename, 1);
                    app.samples = app.samples(:, 1);
                    if (numel(app.samples) > 0)
                        app.InfoTextArea.Value = 'Loaded bearing samples. Please review bearing and sample setup and carefully before running analysis.';
                    end
                else
                    app.InfoTextArea.Value = 'Unrecognized filename and unable to load data.';
                end
            else
                app.InfoTextArea.Value = 'There is no file specified to load';
            end
            if (numel(app.samples) == 0)
                app.InfoTextArea.Value = [app.InfoTextArea.Value 'Could not load any bearing data.'];
            end
            close(d);
        end

        function Analyze(app)
            d = uiprogressdlg(app.BearingAnalyzerUIFigure,'Title','Analyzing ...', 'Indeterminate','on');

            % Extract fields from data
            app.data.bearing.sr = app.SampleRateEditField.Value;
            app.data.bearing.load = app.LoadEditField.Value;
            app.data.bearing.rate = app.ShaftRateEditField.Value;

            if (numel(app.filename) == 0)
                app.InfoTextArea.Value = 'No filename specified.';
                close(d);
            elseif (app.data.bearing.rate == 0)
                app.InfoTextArea.Value = 'Shaft rate needs to be set.';
                close(d);
            elseif (app.data.bearing.sr == 0)
                app.InfoTextArea.Value = 'Sample rate needs to be set.';
                close(d);
            else
                % Run calculations
                app.fault = calcFaultFreq(app.info, app.data.bearing);
                
                % Setup GUI fields
                app.CageEditField.Value = app.fault.freqs(1);
                app.BallEditField.Value = app.fault.freqs(2);
                app.OuterEditField.Value = app.fault.freqs(3);
                app.InnerEditField.Value = app.fault.freqs(4);
                app.RevEditField.Value = app.fault.freqs(5);
                
                % Bearing plot
                plotBearingFault(app);
                % Display bearing status
                LampDisplay(app);
                % Display bearing image
                %ImageDisplay(app);
                InitVideo(app); % background animation

                if (app.fault.pks.status(app.fault.pks.status > 0))
                    app.InfoTextArea.Value = 'Analysis complete, errors detected. Please review results and adjust analyzer setup if needed.';
                else
                    app.InfoTextArea.Value = 'Analysis complete, no errors detected. Please review results and adjust analyzer setup if needed.';
                end
                close(d);
                
                % final animation
                VideoDisplay(app);
            end
        end
       
        function Init(app)
            app.info = NiceBearing();
            app.RollerDiameterEditField.Value = app.info.rd;
            app.PitchDiameterEditField.Value = app.info.pd;
            app.ContactAngleDegreeEditField.Value = app.info.ca;
            app.NumberofElementsEditField.Value = app.info.ne;
            app.ThresholdEditField.Value = app.info.thr;
            app.MinEditField.Value = app.info.min;
            app.MaxEditField.Value = app.info.max;
            InitLamp(app);
            LoadImages(app);
            %InitImage(app);
            LoadVideos(app);
            InitVideo(app);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
        end

        % Callback function
        function OpenMenuSelected(app, event)
            % https://www.mathworks.com/help/matlab/creating_guis/simple-app-designer-example-apps.html
            LoadFile(app);
        end

        % Callback function: BearingAnalyzerUIFigure, ExitMenu
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            LoadFile(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            Analyze(app);
        end

        % Value changed function: RollerDiameterEditField
        function RollerDiameterEditFieldValueChanged(app, event)
            value = app.RollerDiameterEditField.Value;
            app.info.rd = value;
        end

        % Value changed function: PitchDiameterEditField
        function PitchDiameterEditFieldValueChanged(app, event)
            value = app.PitchDiameterEditField.Value;
            app.info.pd = value;
        end

        % Value changed function: ContactAngleDegreeEditField
        function ContactAngleDegreeEditFieldValueChanged(app, event)
            value = app.ContactAngleDegreeEditField.Value;
            app.info.ca = value;
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            value = app.NumberofElementsEditField.Value;
            app.info.ne = value;
        end

        % Value changed function: SideDropDown
        function SideDropDownValueChanged(app, event)
            value = app.SideDropDown.Value;
            if strcmp(value, 'Inner')
                app.info.sd = 1;
            elseif strcmp(value, 'Outer')
                app.info.sd = 2;
            end
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
            LoadFile(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.info.thr = value;
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.info.min = value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.info.max = value;
        end

        % Callback function
        function LoadSamplesButtonPushed(app, event)
            LoadFile(app);
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            value = app.SampleRateEditField.Value;
            app.data.bearing.sr = value;
        end

        % Value changed function: LoadEditField
        function LoadEditFieldValueChanged(app, event)
            value = app.LoadEditField.Value;
            app.data.bearing.load = value;
        end

        % Value changed function: ShaftRateEditField
        function ShaftRateEditFieldValueChanged(app, event)
            value = app.ShaftRateEditField.Value;
            app.data.bearing.rate = value;
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            app.data.bearing.gs = app.samples.(value);
            InitLamp(app);
            InitImage(app);
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            value = app.FilesListBox.Value;
            app.filename = value;
        end

        % Button pushed function: AddFileButton
        function AddFileButtonPushed(app, event)
            fileList = fileLoad();
            fileCellList = cellstr(fileList);
            app.FilesListBox.Items{end + 1} = fileCellList{:};
        end

        % Button pushed function: RemoveFileButton
        function RemoveFileButtonPushed(app, event)
            app.FilesListBox.Items = setdiff(app.FilesListBox.Items, app.FilesListBox.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BearingAnalyzerUIFigure = uifigure;
            app.BearingAnalyzerUIFigure.Name = 'Bearing Analyzer';
            app.BearingAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.BearingAnalyzerUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.BearingAnalyzerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.BearingAnalyzerUIFigure);
            app.AnalyzerTab = uitab(app.TabGroup);
            app.AnalyzerTab.Title = 'Analyzer';
            app.BearingSetupPanel = uipanel(app.AnalyzerTab);
            app.BearingSetupPanel.Title = 'Bearing Setup';
            app.RollerDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.RollerDiameterEditFieldLabel.Text = 'Roller Diameter';
            app.RollerDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.RollerDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @RollerDiameterEditFieldValueChanged, true);
            app.ContactAngleDegreeEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.ContactAngleDegreeEditFieldLabel.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.ContactAngleDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @ContactAngleDegreeEditFieldValueChanged, true);
            app.NumberofElementsEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.NumberofElementsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.SideDropDownLabel = uilabel(app.BearingSetupPanel);
            app.SideDropDownLabel.Text = 'Side';
            app.SideDropDown = uidropdown(app.BearingSetupPanel);
            app.SideDropDown.Items = {'Inner', 'Outer'};
            app.SideDropDown.ValueChangedFcn = createCallbackFcn(app, @SideDropDownValueChanged, true);
            app.SideDropDown.Value = 'Outer';
            app.PitchDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.PitchDiameterEditFieldLabel.Text = 'Pitch Diameter';
            app.PitchDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.PitchDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @PitchDiameterEditFieldValueChanged, true);
            app.AnalyzerSetupPanel = uipanel(app.AnalyzerTab);
            app.AnalyzerSetupPanel.Title = 'Analyzer Setup';
            app.ThresholdEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.SampleSetupPanel = uipanel(app.AnalyzerTab);
            app.SampleSetupPanel.Title = 'Sample Setup';
            app.SampleRateEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.0f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.LoadEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.LoadEditField.ValueDisplayFormat = '%.0f';
            app.LoadEditField.ValueChangedFcn = createCallbackFcn(app, @LoadEditFieldValueChanged, true);
            app.ShaftRateEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.ShaftRateEditFieldLabel.Text = 'Shaft Rate';
            app.ShaftRateEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.ShaftRateEditField.ValueDisplayFormat = '%.0f';
            app.ShaftRateEditField.ValueChangedFcn = createCallbackFcn(app, @ShaftRateEditFieldValueChanged, true);
            app.DataDropDownLabel = uilabel(app.SampleSetupPanel);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.SampleSetupPanel);
            app.DataDropDown.Items = {'gs'};
            app.DataDropDown.Editable = 'on';
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'gs';
            app.UIAxes2 = uiaxes(app.AnalyzerTab);
            app.CommandsPanel = uipanel(app.AnalyzerTab);
            app.CommandsPanel.Title = 'Commands';
            app.AnalyzeButton = uibutton(app.CommandsPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.InfoTextAreaLabel = uilabel(app.AnalyzerTab);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.AnalyzerTab);
            app.InfoTextArea.Editable = 'off';
            app.FilesListBoxLabel = uilabel(app.AnalyzerTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.AnalyzerTab);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.AddFileButton = uibutton(app.AnalyzerTab, 'push');
            app.AddFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFileButtonPushed, true);
            app.AddFileButton.Text = '+';
            app.RemoveFileButton = uibutton(app.AnalyzerTab, 'push');
            app.RemoveFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFileButtonPushed, true);
            app.RemoveFileButton.Text = '-';
            app.LoadButton = uibutton(app.AnalyzerTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            title(app.UIAxes, 'Bearing Analyzer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FaultFrequenciesPanel = uipanel(app.ResultsTab);
            app.FaultFrequenciesPanel.Title = 'Fault Frequencies';
            app.BallEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.BallEditFieldLabel.Text = 'Ball';
            app.BallEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.CageEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.CageEditFieldLabel.Text = 'Cage';
            app.CageEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.InnerEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.InnerEditFieldLabel.Text = 'Inner';
            app.InnerEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.OuterEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.OuterEditFieldLabel.Text = 'Outer';
            app.OuterEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.RevEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.RevEditFieldLabel.Text = '1/Rev';
            app.RevEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.StatusLampLabel = uilabel(app.BearingAnalyzerUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.BearingAnalyzerUIFigure);
            app.StatusLamp.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bearingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BearingAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BearingAnalyzerUIFigure)
        end
    end
end